classdef doanck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NghiemyTextArea         matlab.ui.control.TextArea
        NghimyLabel             matlab.ui.control.Label
        MSSVEditField           matlab.ui.control.EditField
        MSSVEditFieldLabel      matlab.ui.control.Label
        HvtnEditField           matlab.ui.control.EditField
        HvtnEditFieldLabel      matlab.ui.control.Label
        NghiemxTextArea         matlab.ui.control.TextArea
        NghimlLabel             matlab.ui.control.Label
        DRAWButton              matlab.ui.control.Button
        Phuongphap              matlab.ui.control.ListBox
        ChnphngphpListBoxLabel  matlab.ui.control.Label
        Nhapsaisoe              matlab.ui.control.NumericEditField
        SaiseLabel              matlab.ui.control.Label
        NhapsodoanconN          matlab.ui.control.NumericEditField
        sonconNLabel            matlab.ui.control.Label
        Nhapgiatricuoixn        matlab.ui.control.NumericEditField
        giihncuixnLabel         matlab.ui.control.Label
        Nhapgiatridaux0         matlab.ui.control.NumericEditField
        giihnux0Label           matlab.ui.control.Label
        Nhapdieukiendauy0       matlab.ui.control.NumericEditField
        iukinuy0Label           matlab.ui.control.Label
        HayNhap                 matlab.ui.control.EditField
        HynhpfxyLabel           matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
     methods (Access = private)
            %Euler
        function [x,y] = Euler(app,fxy,x0,xn,y0,N)
            h=(xn-x0)/N;
            x=x0:h:xn;
            y=zeros(1,numel(x));
            y(1) = y0;
           for i= 1:N
               y(i+1) = y(i)+h*fxy(x(i),y(i));
           end
        end
            %Midpoint
        function [x,y] = Midpoint(app,fxy,x0,xn,y0,N)
        h = (xn-x0) / N;
        x=x0:h:xn;
        y=zeros(1,numel(x));
        y(1) = y0;
        for i=1:N
            y_half = y(i) +(h/2) * fxy(x(i),y(i));
            y(i+1) = y(i) + h*fxy(x(i) + (h/2),y_half);
        end
        end
            %Trapzoid
function [x,y] = Trapzoid(app,fxy,x0,xn,y0,N,e)
            h=(xn - x0)/N;
        x=x0:h:xn;
        y=zeros(1,numel(x));
        y(1)=y0;
        for i=1:N
            y_0=y(i) + h*fxy(x(i),y(i));
            y_1=y(i) +(h/2)*(fxy(x(i),y(i)) + fxy(x(i+1),y_0));
            while abs(y_1 - y_0 ) > e
                y_0= y_1;
                y_1=y(i) +(h/2)*(fxy(x(i),y(i)) + fxy(x(i+1),y_0));
            end
            y(i+1) = y_1;
         end
        end
            %Range-Kutta
        function [x,y] = R_K(app,fxy,x0,xn,y0,N)
        h = (xn - x0)/N;
        x = x0:h:xn;
        y = zeros(1,numel(x)); 
        y(1) = y0;
        for i = 1:N
         k_1 = h*fxy(x(i),y(i));
         k_2 = h*fxy(x(i)+0.5*h,y(i)+0.5*k_1);
         k_3 = h*fxy(x(i)+0.5*h,y(i)+0.5*k_2);
         k_4 = h*fxy(x(i)+h,y(i)+k_3); 
         y(i+1) = y(i) + (1/6)*(k_1 + 2*k_2 + 2*k_3 + k_4);
        end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DRAWButton
        function DRAWButtonPushed(app, event)
            x0 = app.Nhapgiatridaux0.Value;
            xn = app.Nhapgiatricuoixn.Value;
            y0 = app.Nhapdieukiendauy0.Value;
            N =  app.NhapsodoanconN.Value;
            pick = app.Phuongphap.Value;
            fxy = str2func(['@(x,y)', app.HayNhap.Value]);
            switch pick
                case 'Euler'
                    [x,y] = app.Euler(fxy,x0,xn,y0,N);
                    plot(app.UIAxes,x,y)
                    app.UIAxes.Title.String = 'Đồ thị phương pháp Euler';
                case 'Midpoint'
                    [x,y] = app.Midpoint(fxy,x0,xn,y0,N);
                    plot(app.UIAxes,x,y)
                    app.UIAxes.Title.String = ' Đồ thị phương pháp hiện ẩn trung điểm ';
                case 'Trapzoid'
                    e = app.Nhapsaisoe.Value;
                    [x,y] = app.Trapzoid(fxy,x0,xn,y0,N,e);
                    plot(app.UIAxes,x,y)
                    app.UIAxes.Title.String = ' Đồ thị phương pháp hiện ẩn hình thang ';
                case 'Range-Kutta'
                    [x,y]= app.R_K(fxy,x0,xn,y0,N);   
                    plot(app.UIAxes,x,y)
                    app.UIAxes.Title.String = 'Đồ thị phương pháp Range-Kutta ';
            end     
            app.NghiemxTextArea.Value = string(x);
            app.NghiemyTextArea.Value = string(y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HynhpfxyLabel = uilabel(app.UIFigure);
            app.HynhpfxyLabel.Text = 'Hãy nhập f(x,y)';
            app.HayNhap = uieditfield(app.UIFigure, 'text');
            app.HayNhap.Value = '(1-x)*y';
            app.iukinuy0Label = uilabel(app.UIFigure);
            app.iukinuy0Label.Text = 'điều kiện đầu y0';
            app.Nhapdieukiendauy0 = uieditfield(app.UIFigure, 'numeric');
            app.Nhapdieukiendauy0.Value = 1;
            app.giihnux0Label = uilabel(app.UIFigure);
            app.giihnux0Label.Text = 'giới hạn đầu x0';
            app.Nhapgiatridaux0 = uieditfield(app.UIFigure, 'numeric');
            app.giihncuixnLabel = uilabel(app.UIFigure);
            app.giihncuixnLabel.Text = 'giới hạn cuối xn';
            app.Nhapgiatricuoixn = uieditfield(app.UIFigure, 'numeric');
            app.Nhapgiatricuoixn.Value = 5;
            app.sonconNLabel = uilabel(app.UIFigure);
            app.sonconNLabel.Text = 'số đoạn con N';
            app.NhapsodoanconN = uieditfield(app.UIFigure, 'numeric');
            app.NhapsodoanconN.Value = 25;
            app.SaiseLabel = uilabel(app.UIFigure);
            app.SaiseLabel.Text = 'Sai số e';
            app.Nhapsaisoe = uieditfield(app.UIFigure, 'numeric');
            app.Nhapsaisoe.Value = 0.001;
            app.ChnphngphpListBoxLabel = uilabel(app.UIFigure);
            app.ChnphngphpListBoxLabel.Text = 'Chọn phương pháp';
            app.Phuongphap = uilistbox(app.UIFigure);
            app.Phuongphap.Items = {'Euler', 'Midpoint', 'Trapzoid', 'Range-Kutta'};
            app.Phuongphap.Value = 'Euler';
            app.DRAWButton = uibutton(app.UIFigure, 'push');
            app.DRAWButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWButtonPushed, true);
            app.DRAWButton.Text = 'DRAW';
            app.NghimlLabel = uilabel(app.UIFigure);
            app.NghimlLabel.Text = 'Nghiệm x: ';
            app.NghiemxTextArea = uitextarea(app.UIFigure);
            app.HvtnEditFieldLabel = uilabel(app.UIFigure);
            app.HvtnEditFieldLabel.Text = 'Họ và tên';
            app.HvtnEditField = uieditfield(app.UIFigure, 'text');
            app.HvtnEditField.Value = 'Phạm Nguyễn Đan Huy';
            app.MSSVEditFieldLabel = uilabel(app.UIFigure);
            app.MSSVEditFieldLabel.Text = 'MSSV';
            app.MSSVEditField = uieditfield(app.UIFigure, 'text');
            app.MSSVEditField.Value = '22200075';
            app.NghimyLabel = uilabel(app.UIFigure);
            app.NghimyLabel.Text = 'Nghiệm y:';
            app.NghiemyTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doanck

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end